classdef Classification_5class70 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    properties (Access = private)
    Sliders  matlab.ui.control.Slider
    featureNames cell 
    selectedIdx logical
    fname table
    QualityProgress matlab.ui.control.LinearGauge
    QualityFrame matlab.ui.container.Panel
    QualityGauge matlab.ui.control.LinearGauge
    net % model
    PredictionLabel matlab.ui.control.Label
    minMaxValues
    ScaledValueLabels matlab.ui.control.Label
end

methods (Access = private)

    function createSliders(app, selectedFeatures)
    disp(length(selectedFeatures))
    n = numel(selectedFeatures);% Number of selected features
    app.Sliders = matlab.ui.control.Slider.empty(n,0);% Initialize empty array for sliders
    app.ScaledValueLabels = matlab.ui.control.Label.empty(n,0);% Initialize empty array for scaled value labels

    baseX = 50; baseY = 605;
    panelWidth = 600; panelHeight = 50;
    sliderWidth = 300; sliderHeight = 3;
    labelHeight = 20; spacing = panelHeight + 10;

    mins = table2array(varfun(@min, app.fname(:, selectedFeatures)));% Minimum values of features
    maxs = table2array(varfun(@max, app.fname(:, selectedFeatures)));% Maximum values of features
    app.minMaxValues = struct();

    for i = 1:n
        % === PANEL for each slider ===
        panel = uipanel(app.UIFigure);
        panel.Position = [baseX, baseY - (i - 1)*spacing, panelWidth, panelHeight];
        panel.BackgroundColor = [0.64 0.54 0.68];  % Set panel background color (purple-ish)
        panel.BorderType = 'line';  % Set panel border style
    
        % === Feature header (bold label instead of panel.Title) ===
        featureLabel = uilabel(app.UIFigure);
        featureLabel.Text = selectedFeatures{i};  % Set feature name text
        featureLabel.FontWeight = 'bold';          % Make label text bold
        featureLabel.Position = [baseX + 5, baseY - (i-1)*spacing + panelHeight - 20, 200, labelHeight];
    
        % === Numeric edit field (left side of slider) ===
        editField = uieditfield(panel, 'numeric');
        editField.Position = [10, 10, 50, 22];  % Position inside panel: 10 px from left and bottom
        minVal = mins(i);                       % Minimum value of the feature
        maxVal = maxs(i);                       % Maximum value of the feature
        editField.Limits = [minVal, maxVal];   % Set allowable range for numeric field
        editField.Value = mean([minVal, maxVal]); % Set initial value to mid-point of range
        editField.Tag = selectedFeatures{i};   % Tag to identify feature associated with this edit field
    
        % === Slider inside the panel ===
        sld = uislider(panel);
        sld.Position = [110, 10, sliderWidth, sliderHeight];  % Position slider relative to panel
        sld.Limits = [minVal, maxVal];       % Set slider limits to feature range
        sld.Value = editField.Value;          % Initialize slider value same as edit field
        sld.Tag = selectedFeatures{i};       % Tag slider with feature name
        app.Sliders(i) = sld;                 % Store slider handle in app property array
        
        % === Synchronize slider <-> edit field changes ===
        sld.ValueChangingFcn = @(src, event) sliderChangedSync(app, event, i, editField);
        editField.ValueChangedFcn = @(src, event) editFieldChangedSync(app, src, i);
    
        % === Label showing scaled slider value ===
        scaledLbl = uilabel(panel);
        scaledLbl.Position = [430, 0, 100, labelHeight];  % Position label inside panel
        scaledLbl.Text = sprintf('%.2f', (sld.Value - minVal) / (maxVal - minVal)); % Display normalized value (0 to 1)
        app.ScaledValueLabels(i) = scaledLbl;              % Store label handle in app property array
    
        % === Save min and max values for this feature in struct for later use ===
        featureName = selectedFeatures{i};
        validFieldName = matlab.lang.makeValidName(featureName); % Make valid struct field name
        app.minMaxValues.(validFieldName) = [minVal, maxVal];
    end
end

    function updatePrediction(app)
        % Initialize a vector to store scaled values from sliders
        values = zeros(1, numel(app.Sliders));
        % Loop through each slider to get the raw value and scale it
        for i = 1:numel(app.Sliders)
            rawValue = app.Sliders(i).Value;              % Get current slider value
            featureName = app.Sliders(i).Tag;             % Get feature name associated with this slider
            validFieldName = matlab.lang.makeValidName(featureName); % Make a valid field name for struct
            minmax = app.minMaxValues.(validFieldName);   % Retrieve min and max values for this feature
            
            % Scale raw value to range [0,1]
            scaled = (rawValue - minmax(1)) / (minmax(2) - minmax(1)); 
            values(i) = scaled;                            % Store scaled value
        end
        inputVec = values';  % Convert to column vector for network input
        % Run the neural network prediction
        prediction = app.net(inputVec);
    
        % Convert network output vector to predicted class index and adjust offset (+3)
        predictedClass = vec2ind(prediction) + 3;
    
        % Update prediction label text with the predicted class
        app.PredictionLabel.Text = sprintf('Prediction: %d', predictedClass);
    
        % Update graphical gauge if it exists in the app
        if isprop(app, 'QualityGauge') && ~isempty(app.QualityGauge)
            app.QualityGauge.Value = predictedClass;
        end
    end

    function sliderChanged(app, event, index)
        sld = event.Source;                   % Get the slider that triggered the event
        rawValue = event.Value;               % Get the current raw value from the slider
        featureName = sld.Tag;                % Get the feature name associated with this slider
        validFieldName = matlab.lang.makeValidName(featureName); % Convert feature name to a valid struct field name
        minmax = app.minMaxValues.(validFieldName); % Retrieve min and max values for this feature
        
        % Scale raw slider value to [0,1]
        scaled = (rawValue - minmax(1)) / (minmax(2) - minmax(1));
        
        % Update the corresponding label showing the scaled value
        app.ScaledValueLabels(index).Text = sprintf('%.2f', scaled);
        
        % Update the prediction after the slider value changes
        app.updatePrediction();
    end

    function sliderChangedSync(app, event, index, editField)
        sld = event.Source;                   % Get the slider that triggered the event
        rawValue = event.Value;               % Get the current raw value from the slider
        featureName = sld.Tag;                % Get the feature name associated with this slider
        validFieldName = matlab.lang.makeValidName(featureName); % Convert feature name to a valid struct field name
        minmax = app.minMaxValues.(validFieldName); % Retrieve min and max values for this feature
        
        % Scale raw slider value to [0,1]
        scaled = (rawValue - minmax(1)) / (minmax(2) - minmax(1));
        
        % Update the corresponding label showing the scaled value
        app.ScaledValueLabels(index).Text = sprintf('%.2f', scaled);
        
        % Synchronize the numeric edit field with the slider value
        editField.Value = rawValue;
        
        % Update the prediction after the slider value changes
        app.updatePrediction();
    end


    function editFieldChangedSync(app, editField, index)
        rawValue = editField.Value;               % Get the current value from the numeric edit field
        featureName = editField.Tag;               % Get the feature name associated with this edit field
        validFieldName = matlab.lang.makeValidName(featureName);  % Convert feature name to valid struct field name
        minmax = app.minMaxValues.(validFieldName);               % Retrieve min and max values for this feature
        
        % Clamp the value to the allowed min/max range if necessary
        rawValue = max(min(rawValue, minmax(2)), minmax(1));
        
        % Synchronize the slider's value with the edit field
        app.Sliders(index).Value = rawValue;
        
        % Scale the raw value to [0,1]
        scaled = (rawValue - minmax(1)) / (minmax(2) - minmax(1));
        
        % Update the scaled value label
        app.ScaledValueLabels(index).Text = sprintf('%.2f', scaled);
        
        % Update the prediction after the edit field value changes
        app.updatePrediction();
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100 100 1000 700];       % Set main window position and size

            bg = uiimage(app.UIFigure);                       % Create background image UI element
            bg.ImageSource = 'WineIMG.jpg';                   % Image path (PNG/JPG)
            bg.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)]; % Full background size
            bg.ScaleMethod = 'fill';                          % Scale image to fill entire window
            
            % Get feature names from fname table in base workspace
            app.fname = evalin('base', 'fname');              % Load entire table into app
            %app.fname = readtable('wine-quality.csv');
            app.featureNames = app.fname.Properties.VariableNames(1:11);  % Use first 11 column names as features
            
            app.selectedIdx = true(1, numel(app.featureNames));   % Select all features by default
            selectedFeatures = app.featureNames(app.selectedIdx); % Features chosen for sliders
            
            % Create label to display prediction results
            app.PredictionLabel = uilabel(app.UIFigure);     
            app.PredictionLabel.Position = [300 650 350 50];  % Label position and size
            app.PredictionLabel.Text = 'Prediction: class 4:8 '; % Initial prediction text
            app.PredictionLabel.FontColor = [0.8 0.5 0.8];   % Label text color
            app.PredictionLabel.FontWeight = 'bold';         % Bold font
            app.PredictionLabel.FontSize = 30;                % Font size
            
            % Load pretrained neural network model
            data = load('trainedNetwork.mat', 'net');         
            app.net = data.net;
            
            % Display input size of the model
            inputSize = app.net.inputs{1}.size;               
            disp(['Input size modelu: ', num2str(inputSize)]);  % Show input size in console
            
            % Create sliders based on selected features
            createSliders(app, selectedFeatures);             

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Classification_5class70

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end